classdef curves_map_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CurvesMapAnalyzerbySavoldiTommasoUIFigure  matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ForcecurveLabel               matlab.ui.control.Label
        LoadCurve                     matlab.ui.control.Button
        CalibrationLabel              matlab.ui.control.Label
        aunmLabel                     matlab.ui.control.Label
        CalculateSlopeFromFileButton  matlab.ui.control.Button
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        MainGraph                     matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
        function filename = pickFile(app)
            [file, dir] = uigetfile('*.*');
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if isequal(file, 0)
                uialert(fig, 'File not found.', 'Invalid File', 'Icon', 'error');
                filename = 0;
            else
                filename = fullfile(dir, file);
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            addpath ./functions/
        end

        % Callback function
        function selectCalibrationFileClicked(app, event)
            % Nothing
        end

        % Button pushed function: CalculateSlopeFromFileButton
        function CalculateSlopeFromFileButtonPushed(app, event)
            filename = pickFile(app);
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if ~isequal(filename, 0)
                % Now that the file has been selected, calculate the slope
                % Load the calibration unload curve
                try
                    [~, ~, zu, Nfu] = load_force_curve(filename);
                catch
                    % File can not be read. Alert the user
                    uialert(fig, ['File ' filename ' can not be read. Try removing the first line (it should contain non numeric values).'], 'Valid file', 'Icon', 'error');
                    return
                end
                % Calculate slope starting from 80 % of the max zu to the
                % end and from the top of the curve to the middle point
                slope = calculate_slope(zu, Nfu, 0.80 * max(zu), max(zu));

                % Modify the slopeEditField rounding to the second decimal
                % value
                app.SlopeEditField.Value = round(slope, 2);
                
                % Notify the user
                uialert(fig, ['User selected ' filename ' which gave a slope of ' num2str(slope) ' au/nm.'], 'Valid file', 'Icon', 'success');
            end
        end

        % Button pushed function: LoadCurve
        function LoadCurveButtonPushed(app, event)
            filename = pickFile(app);
            fig = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure;
            if ~isequal(filename, 0)
                % Now that the file has been selected, load the curve
                try
                    [zl, Nfl, zu, Nfu] = load_force_curve(filename);
                catch
                    % File can not be read. Alert the user
                    uialert(fig, ['File ' filename ' can not be read. Try removing the first line (it should contain non numeric values).'], 'Valid file', 'Icon', 'error');
                    return
                end
                % Calculate the deflection by dividing by slope value
                dl = Nfl / app.SlopeEditField.Value;
                du = Nfu / app.SlopeEditField.Value;
                % Activate hold on
                hold(app.MainGraph, 'on');
                % Plot the curve on the mainGraph
                plot(app.MainGraph, zl, dl, 'DisplayName', 'load curve');
                plot(app.MainGraph, zu, du, 'DisplayName', 'unload curve');
                % Show legend
                legend(app.MainGraph, 'show');
                % Notify the user
                uialert(fig, ['Curve contained in file ' filename ' has been successfully loaded.'], 'Valid file', 'Icon', 'success');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure = uifigure('Visible', 'off');
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Name = 'Curves Map Analyzer - by Savoldi Tommaso';
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure);
            app.GridLayout = uigridlayout(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SlopeEditFieldLabel = uilabel(app.LeftPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SlopeEditField.Limits = [1 Inf];
            app.SlopeEditField.ValueDisplayFormat = '%.2f';
            app.SlopeEditField.Value = 1;
            app.CalculateSlopeFromFileButton = uibutton(app.LeftPanel, 'push');
            app.CalculateSlopeFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopeFromFileButtonPushed, true);
            app.CalculateSlopeFromFileButton.Text = 'Calculate from file';
            app.aunmLabel = uilabel(app.LeftPanel);
            app.aunmLabel.Text = 'au/nm';
            app.CalibrationLabel = uilabel(app.LeftPanel);
            app.CalibrationLabel.Text = '- Calibration -';
            app.LoadCurve = uibutton(app.LeftPanel, 'push');
            app.LoadCurve.ButtonPushedFcn = createCallbackFcn(app, @LoadCurveButtonPushed, true);
            app.LoadCurve.Text = 'Load single curve';
            app.ForcecurveLabel = uilabel(app.LeftPanel);
            app.ForcecurveLabel.Text = '- Force curve -';
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainGraph = uiaxes(app.CenterPanel);
            title(app.MainGraph, 'Force curve')
            xlabel(app.MainGraph, 'z [nm]')
            ylabel(app.MainGraph, 'd [nm]')
            zlabel(app.MainGraph, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.CurvesMapAnalyzerbySavoldiTommasoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curves_map_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CurvesMapAnalyzerbySavoldiTommasoUIFigure)
        end
    end
end